classdef CompareInterpolate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
        X_1SpinnerLabel      matlab.ui.control.Label
        X_1Spinner           matlab.ui.control.Spinner
        Y_1SpinnerLabel      matlab.ui.control.Label
        Y_1Spinner           matlab.ui.control.Spinner
        Z_1SpinnerLabel      matlab.ui.control.Label
        Z_1Spinner           matlab.ui.control.Spinner
        X_2Label             matlab.ui.control.Label
        X_2Spinner           matlab.ui.control.Spinner
        Y_2SpinnerLabel      matlab.ui.control.Label
        Y_2Spinner           matlab.ui.control.Spinner
        Z_2SpinnerLabel      matlab.ui.control.Label
        Z_2Spinner           matlab.ui.control.Spinner
        X_3SpinnerLabel      matlab.ui.control.Label
        X_3Spinner           matlab.ui.control.Spinner
        Y_3SpinnerLabel      matlab.ui.control.Label
        Y_3Spinner           matlab.ui.control.Spinner
        Z_3SpinnerLabel      matlab.ui.control.Label
        Z_3Spinner           matlab.ui.control.Spinner
        ModeSwitchKnobLabel  matlab.ui.control.Label
        ModeSwitchKnob       matlab.ui.control.DiscreteKnob
        X_4SpinnerLabel      matlab.ui.control.Label
        X_4Spinner           matlab.ui.control.Spinner
        Y_4SpinnerLabel      matlab.ui.control.Label
        Y_4Spinner           matlab.ui.control.Spinner
        Z_4SpinnerLabel      matlab.ui.control.Label
        Z_4Spinner           matlab.ui.control.Spinner
    end

    
    methods (Access = private)
        
        function B = Bernstein(app, n,j,t)
            B = factorial(n)/(factorial(j)*factorial(n-j))*(t^j)*(1-t)^(n-j);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function X_1SpinnerValueChanged(app, event)
            
        end

        % Value changed function: ModeSwitchKnob
        function ModeSwitchKnobValueChanged(app, event)
            value = app.ModeSwitchKnob.Value;
            value_x = app.X_1Spinner.Value;
            value_y = app.Y_1Spinner.Value;
            value_z = app.Z_1Spinner.Value;
            x = [ 0, value_x];
            y = [ 0, value_y];
            z = [ 0, value_z];
            
            value_x = app.X_2Spinner.Value;
            value_y = app.Y_2Spinner.Value;
            value_z = app.Z_2Spinner.Value;
            x = [ x, value_x];
            y = [ y, value_y];
            z = [ z, value_z];
            
            value_x = app.X_3Spinner.Value;
            value_y = app.Y_3Spinner.Value;
            value_z = app.Z_3Spinner.Value;
            x = [ x, value_x];
            y = [ y, value_y];
            z = [ z, value_z];
            
            value_x = app.X_4Spinner.Value;
            value_y = app.Y_4Spinner.Value;
            value_z = app.Z_4Spinner.Value;
            x = [ x, value_x];
            y = [ y, value_y];
            z = [ z, value_z];
            
            switch value
                case "Spline"
                    xyz = [x ; y ; z];
                    f = cscvn(xyz);
                    t = linspace(0,85,1001);
                    curve = fnval(f,t);
                    plot3(app.UIAxes, curve(1,:), curve(2,:), curve(3,:),'Color','r','LineWidth',2);
                    hold(app.UIAxes,'on');
                    plot3(app.UIAxes, x, y, z, 'o');
                    hold(app.UIAxes,'off');
                    app.UIAxes.XLim = [ 0, 1000];
                    app.UIAxes.YLim = [ 0, 1000];
                    app.UIAxes.ZLim = [ 0, 2000];
                    
                case "Line-Segements"
                    plot3(app.UIAxes_2,x,y,z,'Color','g','LineWidth',2);
                    plot3(app.UIAxes_2, x, y, z, 'o');
                    app.UIAxes_2.XLim = [ 0, 1000];
                    app.UIAxes_2.YLim = [ 0, 1000];
                    app.UIAxes_2.ZLim = [ 0, 2000];
                    
                case "NURBS"
                    
                    t = linspace(0,1,100);
                    xyz = [ x ; y ; z ];
                    for k=1:length(t)
                        Q(:,k)=[0 0 0]';
                        for j=1:size(xyz,2)
                            Q(:,k)=Q(:,k)+xyz(:,j)*Bernstein(app, size(xyz,2)-1,j-1,t(k));
                        end
                    end
                    plot3(app.UIAxes_3, Q(1,:), Q(2,:), Q(3,:),'Color','b','LineWidth',2);
                    plot3(app.UIAxes_3,x,y,z,'o');
                    app.UIAxes_3.XLim = [ 0, 1000];
                    app.UIAxes_3.YLim = [ 0, 1000];
                    app.UIAxes_3.ZLim = [ 0, 2000];
            end
            
            
        end

        % Value changed function: Y_1Spinner
        function Y_1SpinnerValueChanged(app, event)
            value = app.Y_1Spinner.Value;
            
        end

        % Value changed function: Z_1Spinner
        function Z_1SpinnerValueChanged(app, event)
            value = app.Z_1Spinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spline')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Line Segements')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'NURBS')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.X_1SpinnerLabel = uilabel(app.UIFigure);
            app.X_1SpinnerLabel.Text = 'X_1';
            app.X_1Spinner = uispinner(app.UIFigure);
            app.X_1Spinner.Value = 200;
            app.Y_1SpinnerLabel = uilabel(app.UIFigure);
            app.Y_1SpinnerLabel.Text = 'Y_1';
            app.Y_1Spinner = uispinner(app.UIFigure);
            app.Y_1Spinner.ValueChangedFcn = createCallbackFcn(app, @Y_1SpinnerValueChanged, true);
            app.Y_1Spinner.Value = 100;
            app.Z_1SpinnerLabel = uilabel(app.UIFigure);
            app.Z_1SpinnerLabel.Text = 'Z_1';
            app.Z_1Spinner = uispinner(app.UIFigure);
            app.Z_1Spinner.ValueChangedFcn = createCallbackFcn(app, @Z_1SpinnerValueChanged, true);
            app.X_2Label = uilabel(app.UIFigure);
            app.X_2Label.Text = 'X_2';
            app.X_2Spinner = uispinner(app.UIFigure);
            app.X_2Spinner.Value = 300;
            app.Y_2SpinnerLabel = uilabel(app.UIFigure);
            app.Y_2SpinnerLabel.Text = 'Y_2';
            app.Y_2Spinner = uispinner(app.UIFigure);
            app.Y_2Spinner.Value = 300;
            app.Z_2SpinnerLabel = uilabel(app.UIFigure);
            app.Z_2SpinnerLabel.Text = 'Z_2';
            app.Z_2Spinner = uispinner(app.UIFigure);
            app.Z_2Spinner.Value = 150;
            app.X_3SpinnerLabel = uilabel(app.UIFigure);
            app.X_3SpinnerLabel.Text = 'X_3';
            app.X_3Spinner = uispinner(app.UIFigure);
            app.X_3Spinner.Value = 450;
            app.Y_3SpinnerLabel = uilabel(app.UIFigure);
            app.Y_3SpinnerLabel.Text = 'Y_3';
            app.Y_3Spinner = uispinner(app.UIFigure);
            app.Y_3Spinner.Value = 600;
            app.Z_3SpinnerLabel = uilabel(app.UIFigure);
            app.Z_3SpinnerLabel.Text = 'Z_3';
            app.Z_3Spinner = uispinner(app.UIFigure);
            app.Z_3Spinner.Value = 800;
            app.ModeSwitchKnobLabel = uilabel(app.UIFigure);
            app.ModeSwitchKnobLabel.Text = 'Mode Switch';
            app.ModeSwitchKnob = uiknob(app.UIFigure, 'discrete');
            app.ModeSwitchKnob.Items = {'Clear', 'Spline', 'Line-Segements', 'NURBS'};
            app.ModeSwitchKnob.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchKnobValueChanged, true);
            app.ModeSwitchKnob.Value = 'Clear';
            app.X_4SpinnerLabel = uilabel(app.UIFigure);
            app.X_4SpinnerLabel.Text = 'X_4';
            app.X_4Spinner = uispinner(app.UIFigure);
            app.X_4Spinner.Value = 600;
            app.Y_4SpinnerLabel = uilabel(app.UIFigure);
            app.Y_4SpinnerLabel.Text = 'Y_4';
            app.Y_4Spinner = uispinner(app.UIFigure);
            app.Y_4Spinner.Value = 800;
            app.Z_4SpinnerLabel = uilabel(app.UIFigure);
            app.Z_4SpinnerLabel.Text = 'Z_4';
            app.Z_4Spinner = uispinner(app.UIFigure);
            app.Z_4Spinner.Value = 1500;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompareInterpolate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end